classdef SPectrA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        button_load           matlab.ui.control.Button
        button_save           matlab.ui.control.Button
        status_loaded         matlab.ui.control.Lamp
        status_saved          matlab.ui.control.Lamp
        DatasetsinloadedexperimentLabel  matlab.ui.control.Label
        listbox_status        matlab.ui.control.TextArea
        button_edit           matlab.ui.control.Button
        label_filename        matlab.ui.control.Label
        label_dir             matlab.ui.control.Label
        button_go_to_dir      matlab.ui.control.Button
        button_visualization  matlab.ui.control.Button
        label_name            matlab.ui.control.Label
    end


    properties (Access = private)
        child_app                   % Options dialog app
    end
    
    
    properties (Access = public)
        app_dir
        file_results
        file_metadata
        file_dir
        filename
        load_last_dir
        tts
        hsms
        datasets_names
    end
    
    methods (Access = public)
        
        function CloseChildApp(app)
            % Function to close child app
            % ----------------------
            app.child_app.delete
            app.status_saved.Color = 'red';
        end
        
        function FindDatasets(app)
            % Function to find all datasets within a loaded experiment
            % ----------------------
            % find a ROI with the most datasets in it
            rois = fieldnames(app.file_results);
            n_datasets_max = 0;
            n_datasets_max_index = -1;
            for i=1:size(rois)
                n_datasets = length((fieldnames(app.file_results.(rois{i}))));
                if n_datasets > n_datasets_max
                    n_datasets_max = n_datasets;
                    n_datasets_max_index = i;
                end
            end
            
            
            % find datasets
            datasets = app.file_results.(['ROI_' int2str(n_datasets_max_index)]);
            % remove x, y and index (those are ROI specific, not results)
            fields = ["index", "x", "y"];
            datasets = rmfield(datasets, fields);
            % remove SingleParticle if there
            try
                datasets = rmfield(datasets, "SingleParticle");
            catch
                % if not there, do not remove
            end
            
            % make list of TTs and HSMs
            app.datasets_names = fieldnames(datasets);
            
            % find tts
            app.tts = {};
            app.hsms = {};
            for i=1:numel(app.datasets_names)
                if strcmp(datasets.(app.datasets_names{i}).type, 'TT')
                    app.tts{end+1} = app.datasets_names{i};
                elseif strcmp(datasets.(app.datasets_names{i}).type, 'HSM')
                    app.hsms{end+1} = app.datasets_names{i};
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Startup
            % ----------------------
            % finds the folder of the running processing engine and sets the path
            app.app_dir = strrep(mfilename('fullpath'),mfilename,'');
            cd(app.app_dir);
            
            % add folders to path
            addpath(app.app_dir);
            addpath(genpath(app.app_dir));
            app.load_last_dir = app.app_dir;
            
        end

        % Button pushed function: button_load
        function DatasetLoad(app, event)
            % Function to load in experiment
            % ----------------------
            % saved to red
            app.status_saved.Color = 'Red';
            
            % check if already loaded in results
            if isempty(app.file_results) == 0
                answer = questdlg('This will erase the currently loaded in experiment. Are you sure?', ...
                    'Currently loaded experiment will be cleared', ...
                    'Yes', 'No', 'Yes');
                switch answer
                    case 'Yes'
                        app.listbox_status.Value = '';
                        app.file_results = [];
                        app.file_metadata = [];
                    case 'No'
                        return
                end
            end
                        
            app.status_loaded.Color = 'Yellow';
            
            % check if user clicked folder
            [tmp_filename, tmp_pathname] = uigetfile({'*.mat';'*.*'},'Select results to load in. This can be both PLASMON as SPectrA results');
            % prevent minimizing
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
            drawnow;
            figure(app.UIFigure)
            if all(tmp_pathname == 0)
                app.status_loaded.Color = 'Red';
                warndlg('You have to specify a file!','!! Warning !!')
                return
            end
            drawnow;
            figure(app.UIFigure)
            
            % check valid directory
            if ~exist([tmp_pathname '\Metadata.mat'], 'file')
                app.status_loaded.Color = 'Red';
                warndlg('Metadata.mat missing from directory, cannot be loaded! It has to be named Metadata.mat!','!! Warning !!')
                return
            end
            
            % set variables for new dataset
            app.label_dir.Text = tmp_pathname;
            app.file_dir = tmp_pathname;
            app.filename = tmp_filename;
            app.load_last_dir = tmp_pathname;
            split_filename = split(app.file_dir,'\');
            app.label_filename.Text = split_filename(end-1);
            
            % load data
            app.file_results = load([app.file_dir '\' app.filename]);
            app.file_metadata = load([ app.file_dir '\Metadata.mat']);
            
            % sort the ROIs by 1, 2, 3 ... (not ascii based, which matlab
            % saves as)
            fields = fieldnames(app.file_results);
            fields = split(fields, '_');
            fields = cellfun(@str2num, fields(:, 2));
            [~, field_sorted] = sort(fields);
            app.file_results = orderfields(app.file_results, field_sorted);
            
            % find datasets
            FindDatasets(app);
            
            % update listbox
            app.listbox_status.Value = app.datasets_names;
            
            % update buttons
            app.status_loaded.Color = 'Green';
            set(app.button_edit, 'Enable','on')
            set(app.button_save, 'Enable','on')
            set(app.button_visualization, 'Enable','on')
        end

        % Button pushed function: button_save
        function DatasetSave(app, event)
            % Function to save experiment
            % ----------------------
            app.status_saved.Color = 'Yellow';
            
            tmp_struct = app.file_results;
            save([app.file_dir '\Results_SPectrA.mat'], '-struct', 'tmp_struct', '-v7.3');
            clear tmp_struct
            
            app.status_saved.Color = 'Green';
        end

        % Button pushed function: button_go_to_dir
        function OpenFileDir(app, event)
            % Function to open directory of experiment
            % ----------------------
            winopen(app.file_dir);
            
        end

        % Button pushed function: button_edit
        function DatasetEdit(app, event)
            % Function to open Edit page
            % ----------------------
            app.child_app = Page_Edit(app);
            
        end

        % Button pushed function: button_visualization
        function ShowVisualization(app, event)
            % Function to open Visualization page
            % ----------------------
            app.child_app = Page_Visualization(app);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.button_load = uibutton(app.UIFigure, 'push');
            app.button_load.ButtonPushedFcn = createCallbackFcn(app, @DatasetLoad, true);
            app.button_load.Text = 'Load';
            app.button_save = uibutton(app.UIFigure, 'push');
            app.button_save.ButtonPushedFcn = createCallbackFcn(app, @DatasetSave, true);
            app.button_save.Enable = 'off';
            app.button_save.Text = 'Save';
            app.status_loaded = uilamp(app.UIFigure);
            app.status_saved = uilamp(app.UIFigure);
            app.DatasetsinloadedexperimentLabel = uilabel(app.UIFigure);
            app.DatasetsinloadedexperimentLabel.Text = 'Datasets in loaded experiment:';
            app.listbox_status = uitextarea(app.UIFigure);
            app.button_edit = uibutton(app.UIFigure, 'push');
            app.button_edit.ButtonPushedFcn = createCallbackFcn(app, @DatasetEdit, true);
            app.button_edit.Enable = 'off';
            app.button_edit.Text = 'Edit';
            app.label_filename = uilabel(app.UIFigure);
            app.label_filename.Text = 'File Name';
            app.label_dir = uilabel(app.UIFigure);
            app.label_dir.Text = 'Folder dir';
            app.button_go_to_dir = uibutton(app.UIFigure, 'push');
            app.button_go_to_dir.ButtonPushedFcn = createCallbackFcn(app, @OpenFileDir, true);
            app.button_go_to_dir.Text = '>';
            app.button_visualization = uibutton(app.UIFigure, 'push');
            app.button_visualization.ButtonPushedFcn = createCallbackFcn(app, @ShowVisualization, true);
            app.button_visualization.Enable = 'off';
            app.button_visualization.Text = 'Visualization';
            app.label_name = uilabel(app.UIFigure);
            app.label_name.Text = 'SPectrA 5.0.5';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SPectrA

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end