classdef SPectrA_4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        button_load                   matlab.ui.control.Button
        button_save                   matlab.ui.control.Button
        status_loaded                 matlab.ui.control.Lamp
        status_saved                  matlab.ui.control.Lamp
        label_visualize_edit          matlab.ui.control.Label
        button_spectra                matlab.ui.control.Button
        button_functionalization_app  matlab.ui.control.Button
        StatusTextAreaLabel           matlab.ui.control.Label
        listbox_status                matlab.ui.control.TextArea
        EditButton                    matlab.ui.control.Button
        FileNameLabel                 matlab.ui.control.Label
        FolderdirLabel                matlab.ui.control.Label
        GoToFolderButton              matlab.ui.control.Button
        TimetraceAnalysisButton       matlab.ui.control.Button
        PhotoluminescenceAnalysisButton  matlab.ui.control.Button
    end


    properties (Access = private)
        
%         ParticleFile
%         FileFolder
%         FileName
        PopUpApp                   % Options dialog app
    end
    
    
    properties (Access = public)
        ProgramFolder
        ParticleFile
        FileFolder
        FileName
    end

    methods (Access = public) 
        
        function LoadInitialization(app,filename,filefolder,FileND2,threshold)  
             
             app.FileName = filename;
             app.FileFolder = filefolder;
            
             delete(app.PopUpApp)  
             run('scripts\initialize_file.m')
             
                 loadedVars =  load([ app.FileFolder '\' app.FileName '.mat'  ]);
                 app.ParticleFile = loadedVars.Particle;  
                 
                 
             app.FolderdirLabel.Text = app.FileFolder;
             app.FileNameLabel.Text = app.FileName;
             app.listbox_status.Value = fieldnames(app.ParticleFile);
             app.status_loaded.Color = 'Green'; 
             app.status_saved.Color = 'Green'; 
                       
         end         
                
        function RunHSMAnal(app,newFieldName,FileND2,FileSpecCorr,ROI_size_gauss,PclesToAnal,DoSingle)  
            
            delete(app.PopUpApp)  
            run('scripts\run_hsm.m')
            
            app.listbox_status.Value = fieldnames(app.ParticleFile);
            app.status_saved.Color = 'Red'; 
                      
        end
    
        function RunPOLAnal(app,newFieldName,POLFileND2,ROI_size_gauss,MethodToUse, Pol)  
            
            delete(app.PopUpApp)  
            run('scripts\run_pol.m')
            
            app.listbox_status.Value = fieldnames(app.ParticleFile);
            app.status_saved.Color = 'Red'; 
                      
        end  
   
        function RunTimetraceAnal(app,newFieldName,FileND2,ROI_size_gauss,DriftCorrection,IfCrop, MethodToUse,PclesToAnal)  
            
            delete(app.PopUpApp)  
            run('scripts\run_timetrace.m')
            
            app.listbox_status.Value = fieldnames(app.ParticleFile);
            app.status_saved.Color = 'Red';
            
        end
        
        function CallBack(app)  
             
             delete(app.PopUpApp)
                                
             app.listbox_status.Value = fieldnames(app.ParticleFile);
             app.status_saved.Color = 'Red'; 
                       
         end 

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
         
        % finds the folder of the running processing engine and sets the path    
        app.ProgramFolder = strrep(mfilename('fullpath'),mfilename,''); 
        cd(app.ProgramFolder); 
        
        addpath(app.ProgramFolder);
        addpath(genpath(app.ProgramFolder));
                       
        end

        % Callback function
        function AddTimetraceButtonPushed(app, event)

        % Open the options dialog and pass inputs   
        app.PopUpApp = SettingsTimetrace(app,app.FileFolder, app.FileName);

        end

        % Button pushed function: button_load
        function LoadDataset(app, event)
             
            app.status_loaded.Color = 'Yellow';
            
            [filename, pathname] = uigetfile({'*.mat';'*.*'},'File Selector');            
            app.FolderdirLabel.Text = pathname;            
            app.FileFolder = pathname(1:end-1);
            app.FileName = filename(1:end-4);
            app.FileNameLabel.Text = app.FileName;
                         
             IsThereFile = dir([ app.FileFolder '\' app.FileName '.mat' ]);
             
             if isempty(IsThereFile) == 1
                 warndlg('You have to specify the folder and the file!','!! Warning !!')
                 app.status_loaded.Color = 'Red';
             else
                 % load the main file - Particle
                 loadedVars =  load([ app.FileFolder '\' app.FileName '.mat'  ]);
                 app.ParticleFile = loadedVars.Particle;                            
                 app.listbox_status.Value = fieldnames(app.ParticleFile);
                 app.status_loaded.Color = 'Green';                 
             end 
                           
         
        end

        % Button pushed function: button_save
        function SaveDataset(app, event)
                        
        app.status_saved.Color = 'Yellow'; 
            
            Population = load([app.FileFolder '\' app.FileName '_CoordinatesFile.mat' ]);        
            
                for i = 1:Population.N_Object       
                    Population.Pcles.Single(i) = app.ParticleFile(i).SinglePart;       
                    Population.Pcles.Significant(i) = app.ParticleFile(i).SignificantPart;           
                end

            save( [app.FileFolder '\' app.FileName '_CoordinatesFile.mat' ] ,'-struct', 'Population','-v7.3');  % saving the coordinates file

            Particle = app.ParticleFile;
            
                save( [app.FileFolder '\' app.FileName '.mat' ] , 'Particle','-v7.3');  % saving the coordinates file
            
            app.status_saved.Color = 'Green'; 
            
            clear Particle;
            clear Population;
            
        end

        % Callback function
        function AddHSMButtonPushed(app, event)
             
        % Open the options dialog and pass inputs   
        app.PopUpApp = SettingsHSM(app,app.FileFolder, app.FileName);
                                  
        end

        % Callback function
        function NewButtonPushed(app, event)
             
        % Open the options dialog and pass inputs   
        app.PopUpApp = InitializeFiles(app);

        end

        % Button pushed function: button_spectra
        function show_spectra(app, event)
            
        app.PopUpApp = ParticleSpectraApp(app);    
                  
        end

        % Button pushed function: GoToFolderButton
        function GoToFolderButtonPushed(app, event)
            
        winopen(app.FileFolder);    
                        
        end

        % Button pushed function: button_functionalization_app
        function button_functionalization_appPushed(app, event)
            
        app.PopUpApp = FunctionalizationApp(app);    
           
        end

        % Button pushed function: EditButton
        function EditButtonPushed(app, event)
        
        app.PopUpApp = EditFile(app);  
            
        end

        % Button pushed function: TimetraceAnalysisButton
        function TimetraceAnalysisButtonPushed(app, event)
            
        app.PopUpApp = TimetraceAnalApp(app);                        
            
        end

        % Button pushed function: PhotoluminescenceAnalysisButton
        function PhotoluminescenceAnalysisButtonPushed(app, event)
        
        app.PopUpApp = PhotoluminescenceAnal(app);     
            
        end

        % Callback function
        function AddPOLButtonPushed(app, event)
            
        % Open the options dialog and pass inputs   
        app.PopUpApp = SettingsPOL(app,app.FileFolder, app.FileName);
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.button_load = uibutton(app.UIFigure, 'push');
            app.button_load.ButtonPushedFcn = createCallbackFcn(app, @LoadDataset, true);
            app.button_load.Text = 'Load';
            app.button_save = uibutton(app.UIFigure, 'push');
            app.button_save.ButtonPushedFcn = createCallbackFcn(app, @SaveDataset, true);
            app.button_save.Enable = 'off';
            app.button_save.Text = 'Save';
            app.status_loaded = uilamp(app.UIFigure);
            app.status_saved = uilamp(app.UIFigure);
            app.label_visualize_edit = uilabel(app.UIFigure);
            app.label_visualize_edit.Text = 'Visualize & Edit ...';
            app.button_spectra = uibutton(app.UIFigure, 'push');
            app.button_spectra.ButtonPushedFcn = createCallbackFcn(app, @show_spectra, true);
            app.button_spectra.Text = {'Show'; 'Spectra'};
            app.button_functionalization_app = uibutton(app.UIFigure, 'push');
            app.button_functionalization_app.ButtonPushedFcn = createCallbackFcn(app, @button_functionalization_appPushed, true);
            app.button_functionalization_app.Text = {'Functionalization'; 'App'};
            app.StatusTextAreaLabel = uilabel(app.UIFigure);
            app.StatusTextAreaLabel.Text = 'Status:';
            app.listbox_status = uitextarea(app.UIFigure);
            app.EditButton = uibutton(app.UIFigure, 'push');
            app.EditButton.ButtonPushedFcn = createCallbackFcn(app, @EditButtonPushed, true);
            app.EditButton.Text = 'Edit';
            app.FileNameLabel = uilabel(app.UIFigure);
            app.FileNameLabel.Text = 'File Name';
            app.FolderdirLabel = uilabel(app.UIFigure);
            app.FolderdirLabel.Text = 'Folder dir';
            app.GoToFolderButton = uibutton(app.UIFigure, 'push');
            app.GoToFolderButton.ButtonPushedFcn = createCallbackFcn(app, @GoToFolderButtonPushed, true);
            app.GoToFolderButton.Text = '>';
            app.TimetraceAnalysisButton = uibutton(app.UIFigure, 'push');
            app.TimetraceAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @TimetraceAnalysisButtonPushed, true);
            app.TimetraceAnalysisButton.Text = {'Timetrace'; 'Analysis'};
            app.PhotoluminescenceAnalysisButton = uibutton(app.UIFigure, 'push');
            app.PhotoluminescenceAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @PhotoluminescenceAnalysisButtonPushed, true);
            app.PhotoluminescenceAnalysisButton.Text = 'Photoluminescence Analysis';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SPectrA_4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end