classdef Page_TT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        panel_actions                   matlab.ui.container.Panel
        button_contrast                 matlab.ui.control.Button
        button_save_particle            matlab.ui.control.Button
        button_save_all                 matlab.ui.control.Button
        button_signal_hist              matlab.ui.control.Button
        button_snr                      matlab.ui.control.Button
        plot_tt                         matlab.ui.control.UIAxes
        plot_fov                        matlab.ui.control.UIAxes
        plot_spectra                    matlab.ui.control.UIAxes
        plot_spectra_label_SP_lambda    matlab.ui.control.Label
        plot_spectra_label_SP_gamma     matlab.ui.control.Label
        FrameLabel                      matlab.ui.control.Label
        slider_frame                    matlab.ui.control.Slider
        panel_particle                  matlab.ui.container.Panel
        spinner_particle                matlab.ui.control.Spinner
        switch_single                   matlab.ui.control.Switch
        lamp_single                     matlab.ui.control.Lamp
        lamp_significant                matlab.ui.control.Lamp
        switch_significant              matlab.ui.control.Switch
        panel_measurements              matlab.ui.container.Panel
        SpectraLabel                    matlab.ui.control.Label
        drop_spectra                    matlab.ui.control.DropDown
        TimetraceDropDownLabel          matlab.ui.control.Label
        drop_tt                         matlab.ui.control.DropDown
        lamp_spectra                    matlab.ui.control.Lamp
        lamp_tt                         matlab.ui.control.Lamp
        plot_contrast                   matlab.ui.control.UIAxes
        plot_snr                        matlab.ui.control.UIAxes
        plot_signal_levels              matlab.ui.control.UIAxes
        panel_tt_settings               matlab.ui.container.Panel
        button_normalize                matlab.ui.control.StateButton
        ReferenceframeLabel             matlab.ui.control.Label
        entry_ref_frame                 matlab.ui.control.NumericEditField
        button_manual_y_axis            matlab.ui.control.StateButton
        entry_y_axis_max                matlab.ui.control.NumericEditField
        entry_y_axis_min                matlab.ui.control.NumericEditField
        button_rmv_spikes               matlab.ui.control.StateButton
        MinLabel                        matlab.ui.control.Label
        MaxLabel                        matlab.ui.control.Label
        BinWidthSliderLabel             matlab.ui.control.Label
        slider_bin_width                matlab.ui.control.Slider
        plot_scatter                    matlab.ui.control.UIAxes
        FrameSpinnerLabel               matlab.ui.control.Label
        spinner_frame                   matlab.ui.control.Spinner
        TimesEditFieldLabel             matlab.ui.control.Label
        field_time                      matlab.ui.control.NumericEditField
        ScattersettingsPanel            matlab.ui.container.Panel
        checkbox_drift_correction       matlab.ui.control.CheckBox
        checkbox_show_drift_correction  matlab.ui.control.CheckBox
        ContrastSNRsettingsPanel        matlab.ui.container.Panel
        panel_show_particles            matlab.ui.container.ButtonGroup
        button_show_all                 matlab.ui.control.ToggleButton
        button_show_single              matlab.ui.control.ToggleButton
        button_show_significant         matlab.ui.control.ToggleButton
        ContrastreferenceframeLabel     matlab.ui.control.Label
        entry_contrast_frame            matlab.ui.control.NumericEditField
    end

    properties (Access = private)
        
        name_timetrace
        name_hsm
        name_polar
        name_workfield
        tt_to_plot
        bin_width
        sample_fov
        particle_location
        rois
        valid_tt
        valid_hsm
        
    end
    
    properties (Access = public)
        parent_app
    end
    
    
    methods (Access = private)
        
        function n_bins = NumHistBins(app,data)
            % Utility function to compute the number of histogram bins
            % ----------------------
            if app.bin_width == 1
                binwidth = 0.000001;
            elseif app.bin_width == 2
                binwidth = 0.000005;
            elseif app.bin_width == 3
                binwidth = 0.00001;
            elseif app.bin_width == 4
                binwidth = 0.00005;
            elseif app.bin_width == 5
                binwidth = 0.0001;
            elseif app.bin_width == 6
                binwidth = 0.0005;
            elseif app.bin_width == 7
                binwidth = 0.001;
            elseif app.bin_width == 8
                binwidth = 0.005;
            elseif app.bin_width == 9
                binwidth = 0.01;
            elseif app.bin_width == 10
                binwidth = 0.05;
            elseif app.bin_width == 11
                binwidth = 0.1;
            elseif app.bin_width == 12
                binwidth = 0.5;
            elseif app.bin_width == 13
                binwidth = 1;
            end
            
            range2plot = floor(nanmin(data)):nanmean(data)*binwidth:ceil(nanmax(data));
            n_bins = size(range2plot,2);
        end
        
        
        function UpdateSignificantSingleValues(app, roi_number)
            % updates the switches depending on the new particle
            % ----------------------
            % set switches
            app.switch_single.Value = app.parent_app.file_results.(app.rois{roi_number}).SingleParticle;
            app.switch_significant.Value = app.parent_app.file_results.(app.rois{roi_number}).SignificantParticle;
            % set lamps
            if app.switch_single.Value == 1
                app.lamp_single.Color = 'Green';
            else
                app.lamp_single.Color = 'Red';
            end
            
            if app.switch_significant.Value == 1
                app.lamp_significant.Color = 'Cyan';
            else
                app.lamp_significant.Color = 'Red';
            end
        end
        
        function UpdateWithNewParticle(app, roi_number)
            % updates everything when a new particle is selected
            % ----------------------
            % set switches
            UpdateSignificantSingleValues(app, roi_number)
            
            if app.valid_hsm
                UpdateHSM(app, roi_number)
            end
            
            if app.valid_tt
                UpdateTT(app, roi_number)
            end
            
            UpdateParticleView(app, roi_number)
            
        end
        
        function UpdateHSM(app, roi_number)
            % update everything related to HSM
            % ----------------------
            UpdateHSMPlot(app, roi_number)
            % if there is a valid TT, also contrast
            if app.valid_hsm && app.valid_tt
                UpdateTTContrast(app, roi_number, false)
            end
        end
        
        function UpdateHSMPlot(app, roi_number)
            % Creates / Updates the spectrum plot of a ROI
            % ----------------------
            % Particle spectra plot
            if isfield(app.parent_app.file_results.(app.rois{roi_number}), app.name_hsm)
                % convert wavelengths to double
                app.parent_app.file_results.(app.rois{roi_number}).(app.name_hsm).wavelengths = double(app.parent_app.file_results.(app.rois{roi_number}).(app.name_hsm).wavelengths);
                % plot
                wavelength_ev = linspace(1248/(min(app.parent_app.file_results.(app.rois{roi_number}).(app.name_hsm).wavelengths)-30), 1248/(max(app.parent_app.file_results.(app.rois{roi_number}).(app.name_hsm).wavelengths)+30),100);
                plot(app.plot_spectra, app.parent_app.file_results.(app.rois{roi_number}).(app.name_hsm).wavelengths, app.parent_app.file_results.(app.rois{roi_number}).(app.name_hsm).intensity, 'ro', 1248./wavelength_ev, LorentzFunction(app.parent_app.file_results.(app.rois{roi_number}).(app.name_hsm).fit_parameters, wavelength_ev),'r-');
                app.plot_spectra.XLim = [min(app.parent_app.file_results.(app.rois{roi_number}).(app.name_hsm).wavelengths)-30 max(app.parent_app.file_results.(app.rois{roi_number}).(app.name_hsm).wavelengths)+30 ];
                % set labels
                app.plot_spectra_label_SP_lambda.Text = ['λ = ' num2str(round(app.parent_app.file_results.(app.rois{roi_number}).(app.name_hsm).lambda, 1)) ' nm'];
                app.plot_spectra_label_SP_gamma.Text = ['Γ = ' num2str(round(app.parent_app.file_results.(app.rois{roi_number}).(app.name_hsm).linewidth, 1)) ' meV'];
            else
                cla(app.plot_spectra);
                app.plot_spectra_label_SP_lambda.Text = 'No data';
                app.plot_spectra_label_SP_gamma.Text = 'No data';
                
            end
        end
        
        function UpdateTT(app, roi_number)
            % update everything related to TT
            % ----------------------
            UpdateTTPlot(app, roi_number)
            UpdateTTHistogram(app, roi_number, false)
            
            UpdateTTScatter(app, roi_number)
            
            % if there is a valid HSM, also contrast
            if app.valid_tt && app.valid_hsm
                UpdateTTContrast(app, roi_number, false)
            end
            UpdateTTSNR(app, roi_number, false)
        end
        
        function UpdateParticleView(app, roi_number)
            % Creates / Updates the particle view
            % ----------------------
            % plot ROI
            if app.valid_tt && isfield(app.parent_app.file_results.(app.rois{roi_number}), app.name_timetrace)
                imagesc(app.plot_fov, app.parent_app.file_results.(app.rois{roi_number}).(app.name_timetrace).raw(:,:, app.spinner_frame.Value))
                app.plot_fov.XLim = [0.5 size(app.parent_app.file_results.(app.rois{roi_number}).(app.name_timetrace).raw, 1)+0.5];
                app.plot_fov.YLim = [0.5 size(app.parent_app.file_results.(app.rois{roi_number}).(app.name_timetrace).raw, 2)+0.5];
            else
                % get from HSM if no TT
                imagesc(app.plot_fov, app.parent_app.file_results.(app.rois{roi_number}).(app.name_hsm).raw(:,:, floor(end/2)))
                app.plot_fov.XLim = [0.5 size(app.parent_app.file_results.(app.rois{roi_number}).(app.name_hsm).raw, 1)+0.5];
                app.plot_fov.YLim = [0.5 size(app.parent_app.file_results.(app.rois{roi_number}).(app.name_hsm).raw, 2)+0.5];
            end
            
        end
        
        function UpdateTTPlot(app, roi_number)
            % Creates / Updates the intensity plot of a TT
            % ----------------------
            
            if isfield(app.parent_app.file_results.(app.rois{roi_number}), app.name_timetrace)
                try
                    app.tt_to_plot = app.parent_app.file_results.(app.rois{roi_number}).(app.name_timetrace).result(:, 4);
                catch
                    % if this row does not exist, you cannot plot it
                    cla(app.plot_tt);
                    return;
                end
                % find time axis
                try
                    time_axis = app.parent_app.file_metadata.(['meta_' app.name_timetrace(5:end)]).timesteps;
                    time_axis = time_axis(1:size(app.tt_to_plot));
                    found_time = true;
                catch
                    % else use frames
                    time_axis = 1:size(app.tt_to_plot);
                    found_time = false;
                end
                
                % do outlier rejection if desired
                if app.button_rmv_spikes.Value
                    app.tt_to_plot = hampel(app.tt_to_plot, 5);
                end
                
                % normalize if desired
                if app.button_normalize.Value
                    ref = nanmean(app.tt_to_plot(3:app.entry_ref_frame.Value));
                    app.tt_to_plot = app.tt_to_plot ./ ref;
                end
                
                % Update values / slider / spinnners
                UpdateTTDataset(app, length(app.tt_to_plot))
                
                % updating time
                if found_time
                    app.field_time.Value = time_axis(app.spinner_frame.Value);
                    app.plot_tt.XLabel.String = 'Time (s)';
                else
                    app.field_time.Value = 'No Time';
                    app.plot_tt.XLabel.String = 'Frames';
                end
                
                % Plotting the timetrace
                cla(app.plot_tt)
                hold(app.plot_tt,'on')
                plot(app.plot_tt, time_axis(1:app.spinner_frame.Value), app.tt_to_plot(1:app.spinner_frame.Value),'-','color', [1 0 0]);
                plot(app.plot_tt, time_axis(app.spinner_frame.Value:end), app.tt_to_plot(app.spinner_frame.Value:end),'-','color', [1 0.5 0.5]);
                app.plot_tt.Title.String = app.name_timetrace;
                set(app.plot_tt.Title,'Interpreter','none');
                app.plot_tt.XLim = [time_axis(1)  time_axis(end)];
                
                % set y limits
                if app.button_manual_y_axis.Value == 1
                    app.plot_tt.YLim = [app.entry_y_axis_min.Value  app.entry_y_axis_max.Value ];
                else
                    app.plot_tt.YLim = [-Inf Inf];
                end
            else
                cla(app.plot_tt);
            end
        end
        
        function UpdateTTScatter(app, roi_number)
            % Creates / Updates the scatter of the fits
            % ----------------------
            % Check if drift correction or show drift correction desired
            try
                if app.checkbox_drift_correction.Value
                    to_plot_x = app.parent_app.file_results.(app.rois{roi_number}).(app.name_timetrace).result_post_drift(:, 2);
                    to_plot_y = app.parent_app.file_results.(app.rois{roi_number}).(app.name_timetrace).result_post_drift(:, 3);
                elseif app.checkbox_show_drift_correction.Value
                    to_plot_x = app.parent_app.file_results.(app.rois{roi_number}).(app.name_timetrace).drift(:, 1);
                    to_plot_y = app.parent_app.file_results.(app.rois{roi_number}).(app.name_timetrace).drift(:, 2);
                else
                    to_plot_x = app.parent_app.file_results.(app.rois{roi_number}).(app.name_timetrace).result(:, 2);
                    to_plot_y = app.parent_app.file_results.(app.rois{roi_number}).(app.name_timetrace).result(:, 3);
                end
                
                scatter(app.plot_scatter,to_plot_x,to_plot_y,'r.');
            catch
                cla(app.plot_scatter);
            end
            
        end
        
        function UpdateTTHistogram(app, roi_number, to_save)
            % Creates / Updates the intensity histogram figure
            % ----------------------
            if isfield(app.parent_app.file_results.(app.rois{roi_number}), app.name_timetrace)
                cla(app.plot_signal_levels)
                hold(app.plot_signal_levels,'on')
                
                n_bins = NumHistBins(app, app.tt_to_plot);
                
                histogram(app.plot_signal_levels, app.tt_to_plot, n_bins,'BinLimits',[floor(nanmin(app.tt_to_plot))-1 ceil(nanmax(app.tt_to_plot))+1],'FaceColor','red','Orientation','horizontal','LineWidth',0.3);
                % if manual y_axis
                if app.button_manual_y_axis.Value
                    app.plot_signal_levels.YLim = [app.entry_y_axis_min.Value  app.entry_y_axis_max.Value ];
                    lim_y = [app.entry_y_axis_min.Value  app.entry_y_axis_max.Value ];
                else
                    app.plot_signal_levels.YLim = [nanmin(app.tt_to_plot)-1 nanmax(app.tt_to_plot)+1];
                    lim_y = [nanmin(app.tt_to_plot)-1 nanmax(app.tt_to_plot)+1];
                end
            else
                cla(app.plot_signal_levels);
            end
            
            if to_save
                TTSaveHistogram(app.tt_to_plot,n_bins,[floor(nanmin(app.tt_to_plot))-1 ceil(nanmax(app.tt_to_plot))+1],app.parent_app.file_dir,app.name_timetrace,lim_y,app.spinner_particle.Value);
            end
        end
        
        function UpdateTTContrast(app, roi_number, to_save)
            % Creates / Updates the contrast figure
            % ----------------------
            
            % find contrast
            contrast = [];
            wavelength = [];
            
            for k=1:numel(app.rois)
                if app.button_show_all.Value || (app.button_show_single.Value && app.parent_app.file_results.(app.rois{k}).SingleParticle) || (app.button_show_significant.Value && app.parent_app.file_results.(app.rois{k}).SignificantParticle)
                    if isfield(app.parent_app.file_results.(app.rois{k}), app.name_timetrace) && isfield(app.parent_app.file_results.(app.rois{k}), app.name_hsm)
                        tt_intensity = app.parent_app.file_results.(app.rois{k}).(app.name_timetrace).result(:, 4);
                        % if compenstae for spikes
                        if app.button_rmv_spikes.Value
                            % find signal
                            if app.entry_contrast_frame.Value < 6
                                sig = nanmean(hampel(tt_intensity(1:10),5));
                            elseif app.entry_contrast_frame.Value > length(tt_intensity)-6
                                sig = nanmean(hampel(tt_intensity(end-10:end),5));
                            else
                                sig = nanmean(hampel(tt_intensity(app.entry_contrast_frame.Value-5:app.entry_contrast_frame.Value+5),5));
                            end
                            ref = nanmean(hampel(tt_intensity(3:app.entry_ref_frame.Value),5)); %reference value for the contrast
                        else
                            % find signal
                            if app.entry_contrast_frame.Value < 6
                                sig = nanmean(tt_intensity(1:10));
                            elseif app.entry_contrast_frame.Value > length(tt_intensity)-6
                                sig = nanmean(tt_intensity(end-10:end));
                            else
                                sig = nanmean(tt_intensity(app.entry_contrast_frame.Value-5:app.entry_contrast_frame.Value+5));
                            end
                            ref = nanmean(tt_intensity(3:app.entry_ref_frame.Value)); %reference value for the contrast
                        end
                        
                        contrast = [contrast (sig./ref - 1)];
                        wavelength = [wavelength app.parent_app.file_results.(app.rois{k}).(app.name_hsm).lambda];
                        
                        if k==roi_number
                            active_contrast = sig./ref - 1;
                            active_wavelength = app.parent_app.file_results.(app.rois{k}).(app.name_hsm).lambda;
                        end
                    end
                end
                
            end
            
            % if wavelength is filled
            if size(wavelength, 1) > 0
                % Contrast additional calculation and plotting
                wavelength = wavelength( abs(contrast)<2.5 );
                contrast = contrast( abs(contrast)<2.5 );
                wavelength_mu = nanmean(wavelength);
                wavelength_sigma = nanstd(wavelength);
                
                cla(app.plot_contrast);
                hold(app.plot_contrast,'on');
                scatter(app.plot_contrast,wavelength,contrast,'bo');
                if exist('active_contrast','var')
                    scatter(app.plot_contrast,active_wavelength,active_contrast,'ro', 'filled');
                end
                
                app.plot_contrast.XLim = [round(wavelength_mu-3*wavelength_sigma) round(wavelength_mu+3*wavelength_sigma)];
                
                % if manual y_axis
                if app.button_manual_y_axis.Value
                    app.plot_contrast.YLim = [app.entry_y_axis_min.Value-1  app.entry_y_axis_max.Value-1 ];
                else
                    % calculate ylim
                    if -nanmax(contrast) < nanmax(contrast)
                        app.plot_contrast.YLim = [-nanmax(contrast)*1.1  nanmax(contrast)*1.1];
                        lim_y = [-nanmax(contrast)*1.1  nanmax(contrast)*1.1];
                    else
                        app.plot_contrast.YLim = [nanmax(contrast)*1.1  -nanmax(contrast)*1.1];
                        lim_y = [nanmax(contrast)*1.1  -nanmax(contrast)*1.1];
                    end
                end
            else
                cla(app.plot_contrast);
            end
            
            if to_save
                time = app.parent_app.file_metadata.(['meta_' app.name_timetrace(5:end)]).timesteps(app.entry_contrast_frame.Value);
                TTPageSaveContrast(wavelength, contrast, app.parent_app.file_dir, app.name_timetrace, app.entry_contrast_frame.Value, time, lim_y)
            end
        end
        
        
        function UpdateTTSNR(app, roi_number, to_save)
            % Creates / Updates the SNR figure
            % ----------------------
            %   calculate signals
            signal_n = [];
            signal_std = [];
            signal_sn = [];
            
            for k=1:numel(app.rois)
                if app.button_show_all.Value || (app.button_show_single.Value && app.parent_app.file_results.(app.rois{k}).SingleParticle) || (app.button_show_significant.Value && app.parent_app.file_results.(app.rois{k}).SignificantParticle)
                    if isfield(app.parent_app.file_results.(app.rois{k}), app.name_timetrace)
                        
                        % if compensate for spikes
                        if app.button_rmv_spikes.Value
                            ref = nanmean(hampel(app.parent_app.file_results.(app.rois{k}).(app.name_timetrace).result(3:app.entry_ref_frame.Value, 4), 5)); %reference value for the contrast
                            ref_std = nanstd(hampel(app.parent_app.file_results.(app.rois{k}).(app.name_timetrace).result(3:app.entry_ref_frame.Value, 4), 5)); %reference value for the contrast
                        else
                            ref = nanmean(app.parent_app.file_results.(app.rois{k}).(app.name_timetrace).result(3:app.entry_ref_frame.Value, 4)); %reference value for the contrast
                            ref_std = nanstd(app.parent_app.file_results.(app.rois{k}).(app.name_timetrace).result(3:app.entry_ref_frame.Value, 4)); %reference value for the contrast
                        end
                        signal_n = [signal_n ref];
                        signal_std = [signal_std ref_std];
                        signal_sn = [signal_sn sqrt(ref)];
                        
                        if k == roi_number
                            active_signal_n = ref;
                            active_signal_std = ref_std;
                            active_signal_sn = sqrt(ref);
                        end
                    end
                end
                
            end
            
            % if signal_n filled
            if size(signal_n, 1) > 0
                
                cla(app.plot_snr)
                hold(app.plot_snr,'on')
                scatter(app.plot_snr,signal_n,signal_std,'bo');
                scatter(app.plot_snr,signal_n,signal_sn,'ro');
                if exist('active_signal_n', 'var')
                    scatter(app.plot_snr,active_signal_n,active_signal_std,'co', 'filled');
                    scatter(app.plot_snr,active_signal_n,active_signal_sn,'yo', 'filled');
                end
                app.plot_snr.XLim = [0.9*nanmin(signal_n) 1.1*nanmax(signal_n)];
                app.plot_snr.YLim = [-Inf Inf];
                app.plot_snr.XScale = 'log';
                app.plot_snr.YScale = 'log';
            else
                cla(app.plot_snr);
            end
            
            if to_save
                time = app.parent_app.file_metadata.(['meta_' app.name_timetrace(5:end)]).timesteps(app.entry_contrast_frame.Value);
                TTPageSaveSNR(signal_n,signal_std,signal_sn,app.parent_app.file_dir,app.name_timetrace, app.entry_ref_frame.Value,time)
            end
        end
        
        
        function UpdateTTDataset(app, length_tt)
            % set limit of frame slider, spinner, entry contrast, and
            % min/max y-axis entries
            % ----------------------
            app.slider_frame.Limits = [1, length_tt];
            app.spinner_frame.Limits = [1, length_tt];
            app.entry_contrast_frame.Value = length_tt;
            if 200 > length_tt
                app.entry_ref_frame.Value = length_tt;
            else
                app.entry_ref_frame.Value = 200;
            end
            app.entry_contrast_frame.Limits = [1, length_tt];
            app.entry_ref_frame.Limits = [1, length_tt];
            app.entry_y_axis_max.Limits = [app.entry_y_axis_min.Value Inf];
            app.entry_y_axis_min.Limits = [-Inf app.entry_y_axis_max.Value];
        end
        
        function CheckSingleSignificantParticles(app)
            % check if single or significant particles present, otherwise
            % turn of buttons
            % ----------------------
            single_counter = 0;
            significant_counter = 0;
            for k=1:numel(app.rois)
                single_counter = single_counter + app.parent_app.file_results.(app.rois{k}).SingleParticle;
                significant_counter = significant_counter + app.parent_app.file_results.(app.rois{k}).SignificantParticle;
            end
            if single_counter > 0
                app.button_show_single.Enable = 'on';
            else
                app.button_show_single.Enable = 'off';
            end
            if significant_counter > 0
                app.button_show_significant.Enable = 'on';
            else
                app.button_show_significant.Enable = 'off';
            end
        end
        
        function UpdateSwitches(app, roi_number)
            % Updates the switches and their lights for a roi number
            % ----------------------
            % change single value and light
            app.switch_single.Value = app.parent_app.file_results.(app.rois{roi_number}).SingleParticle;
            if app.switch_single.Value
                app.lamp_single.Color = 'Green';
            else
                app.lamp_single.Color = 'Red';
            end
            
            % change significant value and light
            app.switch_significant.Value = app.parent_app.file_results.(app.rois{roi_number}).SignificantParticle;
            if app.switch_significant.Value == 1
                app.lamp_significant.Color = 'Cyan';
            else
                app.lamp_significant.Color = 'Red';
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % Start up
            % ----------------------    
            app.parent_app = mainapp;
            clear mainapp;
            app.lamp_tt.Color = 'Red';
            app.lamp_spectra.Color = 'Red';
            app.rois = fieldnames(app.parent_app.file_results);
            
            % set dropdowns
            app.drop_tt.Items = app.parent_app.tts;
            app.drop_spectra.Items = app.parent_app.hsms;
            
            % if cannot set dropdown, this means that there is no valid
            % TT/HSM present, will skip steps accordingly
            try
                app.name_timetrace = app.drop_tt.Items{1};
                app.valid_tt = true;
            catch
                app.name_timetrace = '';
                app.valid_tt = false;
            end
            try
                app.name_hsm = app.drop_spectra.Items{1};
                app.valid_hsm =  true;
            catch
                app.name_hsm = '';
                app.valid_hsm = false;
            end
            
            
            % if SingleParticle does not exist, create it (and set all
            % single particles to 1, significant to 1)
            if ~isfield(app.parent_app.file_results.ROI_1, 'SingleParticle')
                for k=1:numel(app.rois)
                    app.parent_app.file_results.(app.rois{k}).SingleParticle = 1;
                    app.parent_app.file_results.(app.rois{k}).SignificantParticle = 1;
                end
            end
            
            % set switches and buttons
            app.switch_single.ItemsData = [ 0 , 1 ];
            app.switch_significant.ItemsData = [ 0 , 1 ];
            set(app.button_show_all, 'Enable','on')
            app.spinner_particle.Limits = [1 length(app.rois)];
            
            % check if single or significant particles present, otherwise
            % turn of buttons
            CheckSingleSignificantParticles(app)
            UpdateSwitches(app, app.spinner_particle.Value)
            
            % set up bin width slider
            app.slider_bin_width.MajorTicks = [1 2 3 4 5 6 7 8 9 10 11 12 13];
            app.slider_bin_width.MinorTicks = [];
            app.slider_bin_width.FontSize = 10;
            app.slider_bin_width.MajorTickLabels = {'','','', '', '', '', '', '', '', '', '', '', ''}';
            app.slider_bin_width.Limits = [ 1 13 ];
            app.slider_bin_width.Value = 7;
            app.bin_width = 7;
            
            % update everything to show particle 1
            UpdateWithNewParticle(app, 1)
            
            % set green
            if app.valid_tt
                app.lamp_tt.Color = 'Green';
            end
            if app.valid_hsm
                app.lamp_spectra.Color = 'Green';
            end
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Function to close window
            % ----------------------
            CloseChildApp(app.parent_app);
            delete(app)
            
        end

        % Value changed function: drop_tt
        function DropTTChanged(app, event)
            % Function to change value of TT dropdown
            % ----------------------
            % Load new TT
            app.lamp_tt.Color = 'Yellow';
            app.name_timetrace = app.drop_tt.Value;
            % Change the plots
            if app.valid_tt
                UpdateTT(app, app.spinner_particle.Value)
            end
            app.lamp_tt.Color = 'Green';
        end

        % Value changed function: drop_spectra
        function DropSpectraChanged(app, event)
            % Function to change value of HSM dropdown
            % ----------------------
            app.lamp_spectra.Color = 'Yellow';
            app.name_hsm = app.drop_spectra.Value;
            app.lamp_spectra.Color = 'Green';
            %Change the plots
            if app.valid_hsm
                UpdateHSM(app, app.spinner_particle.Value)
            end
        end

        % Value changed function: spinner_particle
        function SpinnerParticleChanged(app, event)
            % Function that is triggered when new particle is selected
            % ----------------------
            % Update particle
            UpdateSwitches(app, app.spinner_particle.Value)
            % Update dataset if need be
            if app.valid_tt
                UpdateTT(app, app.spinner_particle.Value)
            end
            if app.valid_hsm
                UpdateHSM(app, app.spinner_particle.Value)
            end
            % update particle view
            UpdateParticleView(app, app.spinner_particle.Value)
        end

        % Value changed function: entry_ref_frame
        function TTFrame(app, event)
            % Function triggered when reference frame is changed
            % ----------------------
            if app.valid_tt
                UpdateTT(app, app.spinner_particle.Value)
            end
        end

        % Value changed function: button_normalize
        function TTNormalize(app, event)
            % Function triggered when normalize is clicked
            % ----------------------
            if app.valid_tt
                UpdateTTPlot(app, app.spinner_particle.Value)
                UpdateTTHistogram(app, app.spinner_particle.Value, false)
            end
        end

        % Value changing function: slider_frame
        function SliderFrameChanging(app, event)
            % Function triggered when TT frame slider is moved
            % ----------------------
            % round value and update spinnner
            app.slider_frame.Value = round(event.Value);
            app.spinner_frame.Value = app.slider_frame.Value;
            
            if app.valid_tt
                UpdateTTPlot(app, app.spinner_particle.Value)
                UpdateTTScatter(app, app.spinner_particle.Value)
            end
        end

        % Value changed function: button_rmv_spikes
        function TTRMVSpikes(app, event)
        % Function triggered when remove spikes is clicked
            % ----------------------       
        if app.valid_tt
                UpdateTTPlot(app, app.spinner_particle.Value)
                UpdateTTSNR(app, app.spinner_particle.Value, false)
                if app.valid_hsm
                    UpdateTTContrast(app, app.spinner_particle.Value, false)
                end
            end
        end

        % Value changed function: switch_single
        function SwitchSingleChanged(app, event)
            % Function triggered when particle status (single) is changed
            % ----------------------
            % Change value and light
            app.parent_app.file_results.(app.rois{app.spinner_particle.Value}).SingleParticle = app.switch_single.Value;
            if app.switch_single.Value
                app.lamp_single.Color = 'Green';
            else
                app.lamp_single.Color = 'Red';
            end
            % check again if there are still signle/significant particles
            % left
            CheckSingleSignificantParticles(app)
        end

        % Value changed function: switch_significant
        function SwitchSignificantChanged(app, event)
            % Function triggered when particle status (single) is changed
            % ----------------------
            % Change value and light
            app.parent_app.file_results.(app.rois{app.spinner_particle.Value}).SignificantParticle = app.switch_significant.Value;
            if app.switch_significant.Value == 1
                app.lamp_significant.Color = 'Cyan';
            else
                app.lamp_significant.Color = 'Red';
            end
            % check again if there are still signle/significant particles
            % left
            CheckSingleSignificantParticles(app)
        end

        % Value changed function: spinner_frame
        function SpinnerFrameChanged(app, event)
            % Function triggered when TT frame spinner is changed
            % ----------------------
            % round value and update slider       
            app.spinner_frame.Value = round(event.Value);
            app.slider_frame.Value = app.spinner_frame.Value;
            
            if app.valid_tt
                UpdateTTPlot(app, app.spinner_particle.Value)
                UpdateTTScatter(app, app.spinner_particle.Value)
            end
            
        end

        % Button pushed function: button_contrast
        function ActionsContrast(app, event)
        end

        % Button pushed function: button_save_particle
        function ActionsSaveParticle(app, event)
        % Function triggered when saving individual particle is asked    
        % ----------------------
        TTPageSaveParticle(app.parent_app.file_dir, app.parent_app.file_results.(app.rois{app.spinner_particle.Value}), app.valid_tt, app.name_timetrace, app.valid_hsm, app.name_hsm, app.parent_app.file_metadata)
        end

        % Button pushed function: button_save_all
        function ActionSaveAll(app, event)
        % Function triggered when saving all particles is asked    
        % ----------------------
        for k=1:numel(app.rois)
                TTPageSaveParticle(app.parent_app.file_dir, app.parent_app.file_results.(app.rois{k}), app.valid_tt, app.name_timetrace, app.valid_hsm, app.name_hsm, app.parent_app.file_metadata)
            end
        end

        % Selection changed function: panel_show_particles
        function panel_show_particlesSelectionChanged(app, event)
        % Function triggered when asked to use different particle to make images    
        % ----------------------    
        if app.valid_tt
                if app.valid_hsm
                    UpdateTTContrast(app, app.spinner_particle.Value, false)
                end
                UpdateTTSNR(app, app.spinner_particle.Value, false)
            end
        end

        % Value changed function: button_manual_y_axis
        function TTManualAxis(app, event)
        % Function triggered when asked to use manual axis    
        % ----------------------    
        if app.valid_tt
                UpdateTTPlot(app, app.spinner_particle.Value)
                UpdateTTHistogram(app, app.spinner_particle.Value, false)
                if app.valid_hsm
                    UpdateTTContrast(app, app.spinner_particle.Value, false)
                end
            end
        end

        % Value changed function: slider_bin_width
        function SliderHistBinChanged(app, event)
        % Update the histogram as the slider value for bindwidth changes 
        % ----------------------    
            app.bin_width = round(app.slider_bin_width.Value);
            if app.valid_tt
                UpdateTTHistogram(app, app.spinner_particle.Value, false)
            end
        end

        % Value changing function: slider_bin_width
        function SliderHistBinChanging(app, event)
            % Update the histogram as the slider value for bindwidth changes 
        % ----------------------    
            app.bin_width = round(app.slider_bin_width.Value);
            if app.valid_tt
                UpdateTTHistogram(app, app.spinner_particle.Value, false)
            end
        end

        % Value changed function: checkbox_show_drift_correction
        function DriftCorrectionShowCheckbox(app, event)
            if app.checkbox_drift_correction.Value
                app.checkbox_show_drift_correction.Value = 0;
                warndlg('You only apply drift correction or show drift correction, not at the same time!','!! Warning !!')
            end
            if app.valid_tt
                UpdateTTScatter(app, app.spinner_particle.Value)
            end
        end

        % Value changed function: entry_y_axis_min
        function TTYMinChanged(app, event)
            app.entry_y_axis_max.Limits = [app.entry_y_axis_min.Value Inf];
            if app.valid_tt
                UpdateTTPlot(app, app.spinner_particle.Value)
                UpdateTTHistogram(app, app.spinner_particle.Value, false)
                if app.valid_hsm
                    UpdateTTContrast(app, app.spinner_particle.Value, false)
                end
            end
        end

        % Value changed function: entry_y_axis_max
        function TTYMaxChanged(app, event)
            app.entry_y_axis_min.Limits = [-Inf app.entry_y_axis_max.Value];
            if app.valid_tt
                UpdateTTPlot(app, app.spinner_particle.Value)
                UpdateTTHistogram(app, app.spinner_particle.Value, false)
                if app.valid_hsm
                    UpdateTTContrast(app, app.spinner_particle.Value, false)
                end
            end
        end

        % Button pushed function: button_snr
        function ActionsSNR(app, event)
        if app.valid_tt    
        UpdateTTSNR(app, app.spinner_particle.Value, true)    
        end
        end

        % Button pushed function: button_signal_hist
        function ActionsSignalHist(app, event)
            if app.valid_tt
                UpdateTTHistogram(app, app.spinner_particle.Value, true)
            end
        end

        % Value changed function: checkbox_drift_correction
        function DriftCorrectionCheckbox(app, event)
            if app.checkbox_show_drift_correction.Value
                app.checkbox_drift_correction.Value = 0;
                warndlg('You only apply drift correction or show drift correction, not at the same time!','!! Warning !!')
            end
            
            if app.valid_tt
                UpdateTTScatter(app, app.spinner_particle.Value)
            end
        end

        % Value changed function: entry_contrast_frame
        function ContrastReferenceFrameChanged(app, event)
            if app.valid_tt && app.valid_hsm
                UpdateTTContrast(app, app.spinner_particle.Value, false)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.panel_actions = uipanel(app.UIFigure);
            app.panel_actions.Title = 'Save figures';
            app.button_contrast = uibutton(app.panel_actions, 'push');
            app.button_contrast.ButtonPushedFcn = createCallbackFcn(app, @ActionsContrast, true);
            app.button_contrast.Text = 'Contrast';
            app.button_save_particle = uibutton(app.panel_actions, 'push');
            app.button_save_particle.ButtonPushedFcn = createCallbackFcn(app, @ActionsSaveParticle, true);
            app.button_save_particle.Text = 'Particle';
            app.button_save_all = uibutton(app.panel_actions, 'push');
            app.button_save_all.ButtonPushedFcn = createCallbackFcn(app, @ActionSaveAll, true);
            app.button_save_all.Text = 'All Particles';
            app.button_signal_hist = uibutton(app.panel_actions, 'push');
            app.button_signal_hist.ButtonPushedFcn = createCallbackFcn(app, @ActionsSignalHist, true);
            app.button_signal_hist.Text = 'TT Histogram';
            app.button_snr = uibutton(app.panel_actions, 'push');
            app.button_snr.ButtonPushedFcn = createCallbackFcn(app, @ActionsSNR, true);
            app.button_snr.Text = 'SNR';
            app.plot_tt = uiaxes(app.UIFigure);
            title(app.plot_tt, 'Title')
            xlabel(app.plot_tt, 'Time (s)')
            ylabel(app.plot_tt, 'Scattered intensity (arb.u.)')
            app.plot_fov = uiaxes(app.UIFigure);
            app.plot_spectra = uiaxes(app.UIFigure);
            title(app.plot_spectra, 'Spectrum')
            xlabel(app.plot_spectra, 'Wavelength (nm)')
            ylabel(app.plot_spectra, 'Scat. cross sec. (arb.u.)')
            app.plot_spectra_label_SP_lambda = uilabel(app.UIFigure);
            app.plot_spectra_label_SP_gamma = uilabel(app.UIFigure);
            app.FrameLabel = uilabel(app.UIFigure);
            app.FrameLabel.Text = 'Frame';
            app.slider_frame = uislider(app.UIFigure);
            app.slider_frame.ValueChangingFcn = createCallbackFcn(app, @SliderFrameChanging, true);
            app.panel_particle = uipanel(app.UIFigure);
            app.panel_particle.Title = 'Particle';
            app.spinner_particle = uispinner(app.panel_particle);
            app.spinner_particle.Limits = [1 Inf];
            app.spinner_particle.RoundFractionalValues = 'on';
            app.spinner_particle.ValueDisplayFormat = '%.0f';
            app.spinner_particle.ValueChangedFcn = createCallbackFcn(app, @SpinnerParticleChanged, true);
            app.spinner_particle.Value = 1;
            app.switch_single = uiswitch(app.panel_particle, 'slider');
            app.switch_single.Items = {'CLUSTER', 'SINGLE'};
            app.switch_single.ItemsData = {'0', '1'};
            app.switch_single.ValueChangedFcn = createCallbackFcn(app, @SwitchSingleChanged, true);
            app.switch_single.Value = '1';
            app.lamp_single = uilamp(app.panel_particle);
            app.lamp_significant = uilamp(app.panel_particle);
            app.switch_significant = uiswitch(app.panel_particle, 'slider');
            app.switch_significant.Items = {'no', 'SIGNIFICANT'};
            app.switch_significant.ItemsData = {'0', '1'};
            app.switch_significant.ValueChangedFcn = createCallbackFcn(app, @SwitchSignificantChanged, true);
            app.switch_significant.Value = '1';
            app.panel_measurements = uipanel(app.UIFigure);
            app.panel_measurements.Title = 'Datasets';
            app.SpectraLabel = uilabel(app.panel_measurements);
            app.SpectraLabel.Text = 'Spectra';
            app.drop_spectra = uidropdown(app.panel_measurements);
            app.drop_spectra.Items = {'', 'Option 2', 'Option 3', 'Option 4'};
            app.drop_spectra.ValueChangedFcn = createCallbackFcn(app, @DropSpectraChanged, true);
            app.drop_spectra.Value = '';
            app.TimetraceDropDownLabel = uilabel(app.panel_measurements);
            app.TimetraceDropDownLabel.Text = 'Timetrace';
            app.drop_tt = uidropdown(app.panel_measurements);
            app.drop_tt.Items = {'', 'Option 2', 'Option 3', 'Option 4'};
            app.drop_tt.ValueChangedFcn = createCallbackFcn(app, @DropTTChanged, true);
            app.drop_tt.Value = '';
            app.lamp_spectra = uilamp(app.panel_measurements);
            app.lamp_tt = uilamp(app.panel_measurements);
            app.plot_contrast = uiaxes(app.UIFigure);
            title(app.plot_contrast, 'Contrast')
            xlabel(app.plot_contrast, 'Wavelength (nm)')
            ylabel(app.plot_contrast, 'Contrast (arb.u.)')
            app.plot_snr = uiaxes(app.UIFigure);
            title(app.plot_snr, 'SNR')
            xlabel(app.plot_snr, 'N')
            ylabel(app.plot_snr, 'SNR and SN')
            app.plot_signal_levels = uiaxes(app.UIFigure);
            app.panel_tt_settings = uipanel(app.UIFigure);
            app.panel_tt_settings.Title = 'TT Settings';
            app.button_normalize = uibutton(app.panel_tt_settings, 'state');
            app.button_normalize.ValueChangedFcn = createCallbackFcn(app, @TTNormalize, true);
            app.button_normalize.Text = 'Normalize';
            app.ReferenceframeLabel = uilabel(app.panel_tt_settings);
            app.ReferenceframeLabel.Text = {'Reference'; 'frame'};
            app.entry_ref_frame = uieditfield(app.panel_tt_settings, 'numeric');
            app.entry_ref_frame.Limits = [4 Inf];
            app.entry_ref_frame.ValueDisplayFormat = '%.0f';
            app.entry_ref_frame.ValueChangedFcn = createCallbackFcn(app, @TTFrame, true);
            app.entry_ref_frame.Value = 200;
            app.button_manual_y_axis = uibutton(app.panel_tt_settings, 'state');
            app.button_manual_y_axis.ValueChangedFcn = createCallbackFcn(app, @TTManualAxis, true);
            app.button_manual_y_axis.Text = 'Manual Y axis';
            app.entry_y_axis_max = uieditfield(app.panel_tt_settings, 'numeric');
            app.entry_y_axis_max.ValueChangedFcn = createCallbackFcn(app, @TTYMaxChanged, true);
            app.entry_y_axis_max.Value = 1.1;
            app.entry_y_axis_min = uieditfield(app.panel_tt_settings, 'numeric');
            app.entry_y_axis_min.ValueChangedFcn = createCallbackFcn(app, @TTYMinChanged, true);
            app.entry_y_axis_min.Value = 0.9;
            app.button_rmv_spikes = uibutton(app.panel_tt_settings, 'state');
            app.button_rmv_spikes.ValueChangedFcn = createCallbackFcn(app, @TTRMVSpikes, true);
            app.button_rmv_spikes.Text = 'Remove spikes';
            app.MinLabel = uilabel(app.panel_tt_settings);
            app.MinLabel.Text = 'Min';
            app.MaxLabel = uilabel(app.panel_tt_settings);
            app.MaxLabel.Text = 'Max';
            app.BinWidthSliderLabel = uilabel(app.UIFigure);
            app.BinWidthSliderLabel.Text = 'Bin Width';
            app.slider_bin_width = uislider(app.UIFigure);
            app.slider_bin_width.Limits = [1 9];
            app.slider_bin_width.MajorTicks = [1 2 3 4 5 6 7 8 9];
            app.slider_bin_width.ValueChangedFcn = createCallbackFcn(app, @SliderHistBinChanged, true);
            app.slider_bin_width.ValueChangingFcn = createCallbackFcn(app, @SliderHistBinChanging, true);
            app.slider_bin_width.MinorTicks = [];
            app.slider_bin_width.Value = 5;
            app.plot_scatter = uiaxes(app.UIFigure);
            app.FrameSpinnerLabel = uilabel(app.UIFigure);
            app.FrameSpinnerLabel.Text = 'Frame:';
            app.spinner_frame = uispinner(app.UIFigure);
            app.spinner_frame.ValueChangedFcn = createCallbackFcn(app, @SpinnerFrameChanged, true);
            app.TimesEditFieldLabel = uilabel(app.UIFigure);
            app.TimesEditFieldLabel.Text = 'Time (s):';
            app.field_time = uieditfield(app.UIFigure, 'numeric');
            app.field_time.Editable = 'off';
            app.ScattersettingsPanel = uipanel(app.UIFigure);
            app.ScattersettingsPanel.Title = 'Scatter settings';
            app.checkbox_drift_correction = uicheckbox(app.ScattersettingsPanel);
            app.checkbox_drift_correction.ValueChangedFcn = createCallbackFcn(app, @DriftCorrectionCheckbox, true);
            app.checkbox_drift_correction.Text = {'Correct for '; 'drift'};
            app.checkbox_show_drift_correction = uicheckbox(app.ScattersettingsPanel);
            app.checkbox_show_drift_correction.ValueChangedFcn = createCallbackFcn(app, @DriftCorrectionShowCheckbox, true);
            app.checkbox_show_drift_correction.Text = {'Show drift'; 'correction'};
            app.ContrastSNRsettingsPanel = uipanel(app.UIFigure);
            app.ContrastSNRsettingsPanel.Title = 'Contrast & SNR settings';
            app.panel_show_particles = uibuttongroup(app.ContrastSNRsettingsPanel);
            app.panel_show_particles.SelectionChangedFcn = createCallbackFcn(app, @panel_show_particlesSelectionChanged, true);
            app.panel_show_particles.Title = 'Show particles:';
            app.button_show_all = uitogglebutton(app.panel_show_particles);
            app.button_show_all.Text = 'All';
            app.button_show_all.Value = true;
            app.button_show_single = uitogglebutton(app.panel_show_particles);
            app.button_show_single.Text = 'Single';
            app.button_show_significant = uitogglebutton(app.panel_show_particles);
            app.button_show_significant.Text = 'Significant';
            app.ContrastreferenceframeLabel = uilabel(app.ContrastSNRsettingsPanel);
            app.ContrastreferenceframeLabel.Text = 'Contrast reference frame:';
            app.entry_contrast_frame = uieditfield(app.ContrastSNRsettingsPanel, 'numeric');
            app.entry_contrast_frame.Limits = [0 Inf];
            app.entry_contrast_frame.ValueDisplayFormat = '%.0f';
            app.entry_contrast_frame.ValueChangedFcn = createCallbackFcn(app, @ContrastReferenceFrameChanged, true);
            app.entry_contrast_frame.Value = 6000;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Page_TT(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end