classdef Page_Visualization < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        panel_particle                  matlab.ui.container.Panel
        spinner_particle                matlab.ui.control.Spinner
        switch_single                   matlab.ui.control.Switch
        lamp_single                     matlab.ui.control.Lamp
        panel_single_particles          matlab.ui.container.Panel
        label_r2                        matlab.ui.control.Label
        button_single_particles         matlab.ui.control.Button
        entry_r2_min                    matlab.ui.control.NumericEditField
        label_r2_min                    matlab.ui.control.Label
        entry_lambda_max                matlab.ui.control.NumericEditField
        entry_lambda_min                matlab.ui.control.NumericEditField
        label_lambda_min                matlab.ui.control.Label
        label_lambda_max                matlab.ui.control.Label
        label_lambda                    matlab.ui.control.Label
        entry_linewidth_max             matlab.ui.control.NumericEditField
        entry_linewidth_min             matlab.ui.control.NumericEditField
        label_linewidth_min             matlab.ui.control.Label
        label_linewidth_max             matlab.ui.control.Label
        label_linewidth                 matlab.ui.control.Label
        panel_datasets                  matlab.ui.container.Panel
        SpectraLabel                    matlab.ui.control.Label
        drop_spectra                    matlab.ui.control.DropDown
        TimetraceDropDownLabel          matlab.ui.control.Label
        drop_tt                         matlab.ui.control.DropDown
        lamp_spectra                    matlab.ui.control.Lamp
        lamp_tt                         matlab.ui.control.Lamp
        plot_contrast                   matlab.ui.control.UIAxes
        panel_contrast_settings         matlab.ui.container.Panel
        panel_show_particles            matlab.ui.container.ButtonGroup
        button_show_all                 matlab.ui.control.ToggleButton
        button_show_single              matlab.ui.control.ToggleButton
        ContrastframeLabel              matlab.ui.control.Label
        entry_contrast_frame            matlab.ui.control.NumericEditField
        ContrastreferenceframeLabel     matlab.ui.control.Label
        entry_ref_frame                 matlab.ui.control.NumericEditField
        panel_spectrum                  matlab.ui.container.Panel
        plot_spectra                    matlab.ui.control.UIAxes
        plot_spectra_label_SP_lambda    matlab.ui.control.Label
        plot_spectra_label_SP_gamma     matlab.ui.control.Label
        panel_manual_fit                matlab.ui.container.Panel
        manual_fit_par2                 matlab.ui.control.NumericEditField
        ParametersEditFieldLabel        matlab.ui.control.Label
        manual_fit_par1                 matlab.ui.control.NumericEditField
        manual_fit_par4                 matlab.ui.control.NumericEditField
        manual_fit_button               matlab.ui.control.Button
        manual_fit_button_save          matlab.ui.control.Button
        DatapointsLabel                 matlab.ui.control.Label
        manual_fit_datapoints           matlab.ui.control.EditField
        manual_fit_par3                 matlab.ui.control.NumericEditField
        plot_hsm_fov                    matlab.ui.control.UIAxes
        HSMFrameLabel                   matlab.ui.control.Label
        slider_hsm_frame                matlab.ui.control.Slider
        plot_spectra_label_SP_r2        matlab.ui.control.Label
        panel_save                      matlab.ui.container.Panel
        button_contrast                 matlab.ui.control.Button
        button_save_particle            matlab.ui.control.Button
        button_save_all                 matlab.ui.control.Button
        panel_tt                        matlab.ui.container.Panel
        plot_tt_fov                     matlab.ui.control.UIAxes
        panel_tt_settings               matlab.ui.container.Panel
        button_normalize                matlab.ui.control.StateButton
        button_manual_y_axis            matlab.ui.control.StateButton
        entry_y_axis_max                matlab.ui.control.NumericEditField
        entry_y_axis_min                matlab.ui.control.NumericEditField
        button_rmv_spikes               matlab.ui.control.StateButton
        label_y_axis_min                matlab.ui.control.Label
        label_y_axis_max                matlab.ui.control.Label
        plot_scatter                    matlab.ui.control.UIAxes
        panel_tt_scatter_settings       matlab.ui.container.Panel
        checkbox_drift_correction       matlab.ui.control.CheckBox
        checkbox_show_drift_correction  matlab.ui.control.CheckBox
        plot_tt                         matlab.ui.control.UIAxes
        slider_frame                    matlab.ui.control.Slider
        label_time                      matlab.ui.control.Label
        field_time                      matlab.ui.control.NumericEditField
        label_frame_spinner             matlab.ui.control.Label
        spinner_frame                   matlab.ui.control.Spinner
    end

 
    properties (Access = public)
        parent_app
        name_timetrace
        name_hsm
        tt_to_plot
        rois
        valid_tt
        valid_hsm
        contrast_info
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            VisualizationStartup(app, mainapp);
            % couple click to contrast plot
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @VisualizationClickOnContrast, true);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            VisualizationClose(app, event);
        end

        % Value changed function: drop_tt
        function DropTTChanged(app, event)
            VisualizationDropTTChanged(app, event);
        end

        % Value changed function: drop_spectra
        function DropSpectraChanged(app, event)
            VisualizationDropSpectraChanged(app, event);
        end

        % Value changed function: spinner_particle
        function SpinnerParticleChanged(app, event)
            VisualizationSpinnerParticleChanged(app, event);
        end

        % Value changed function: entry_ref_frame
        function TTFrame(app, event)
            VisualizationTTFrame(app, event);
        end

        % Value changed function: button_normalize
        function TTNormalize(app, event)
            VisualizationTTNormalize(app, event);
        end

        % Value changing function: slider_frame
        function SliderFrameChanging(app, event)
            VisualizationSliderFrameChanging(app, event);
        end

        % Value changed function: button_rmv_spikes
        function TTRMVSpikes(app, event)
            VisualizationTTRMVSpikes(app, event);
        end

        % Value changed function: switch_single
        function SwitchSingleChanged(app, event)
            VisualizationSwitchSingleChanged(app, event);
        end

        % Value changed function: spinner_frame
        function SpinnerFrameChanged(app, event)
            VisualizationSpinnerFrameChanged(app, event);            
        end

        % Button pushed function: button_contrast
        function ActionsContrast(app, event)
            % Function triggered when saving contrast figure is asked
            % ----------------------
            VisualizationUpdateTTContrast(app, app.spinner_particle.Value, true)
        end

        % Button pushed function: button_save_particle
        function ActionsSaveParticle(app, event)
            % Function triggered when saving individual particle is asked
            % ----------------------
            VisualizationSaveParticle(app.parent_app.file_dir, app.parent_app.file_results.(app.rois{app.spinner_particle.Value}), app.valid_tt, app.name_timetrace, app.valid_hsm, app.name_hsm, app.parent_app.file_metadata)
        end

        % Button pushed function: button_save_all
        function ActionSaveAll(app, event)
            VisualizationSaveAll(app, event);
        end

        % Selection changed function: panel_show_particles
        function ParticleSelectionChanged(app, event)
            VisualizationParticleSelectionChanged(app, event);
        end

        % Value changed function: button_manual_y_axis
        function TTManualAxis(app, event)
            VisualizationTTManualAxis(app, event);
        end

        % Value changed function: checkbox_show_drift_correction
        function DriftCorrectionShowCheckbox(app, event)
            VisualizationDriftCorrectionShowCheckbox(app, event);
        end

        % Value changed function: entry_y_axis_min
        function TTYMinChanged(app, event)
            VisualizationTTYMinChanged(app, event);
        end

        % Value changed function: entry_y_axis_max
        function TTYMaxChanged(app, event)
            VisualizationTTYMaxChanged(app, event);
        end

        % Value changed function: checkbox_drift_correction
        function DriftCorrectionCheckbox(app, event)
            VisualizationDriftCorrectionCheckbox(app, event);
        end

        % Value changed function: entry_contrast_frame
        function ContrastReferenceFrameChanged(app, event)
            VisualizationContrastReferenceFrameChanged(app, event);
        end

        % Value changing function: slider_hsm_frame
        function SliderHSMFrameChanging(app, event)
            VisualizationSliderHSMFrameChanging(app, event);
        end

        % Button pushed function: manual_fit_button
        function DoManualHSMFit(app, event)
            % Function triggered when you click the manual fit button of HSM, generating a manual fit
            % ---------------------
            VisualizationManualHSMFit(app, app.spinner_particle.Value, false)
        end

        % Button pushed function: manual_fit_button_save
        function ManualFitSave(app, event)
            % Function triggered when you click the manual fit button of HSM WITH SAVE, generating a manual
            % fit and saving it
            % ---------------------
            VisualizationManualHSMFit(app, app.spinner_particle.Value, true)
        end

        % Button pushed function: button_single_particles
        function ButtonDetermineSingleParticls(app, event)
            VisualizationButtonDetermineSingleParticls(app, event);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.panel_particle = uipanel(app.UIFigure);
            app.panel_particle.Title = 'Particle';
            app.spinner_particle = uispinner(app.panel_particle);
            app.spinner_particle.Limits = [1 Inf];
            app.spinner_particle.RoundFractionalValues = 'on';
            app.spinner_particle.ValueDisplayFormat = '%.0f';
            app.spinner_particle.ValueChangedFcn = createCallbackFcn(app, @SpinnerParticleChanged, true);
            app.spinner_particle.Value = 1;
            app.switch_single = uiswitch(app.panel_particle, 'slider');
            app.switch_single.Items = {'CLUSTER', 'SINGLE'};
            app.switch_single.ItemsData = {'0', '1'};
            app.switch_single.ValueChangedFcn = createCallbackFcn(app, @SwitchSingleChanged, true);
            app.switch_single.Value = '1';
            app.lamp_single = uilamp(app.panel_particle);
            app.panel_single_particles = uipanel(app.panel_particle);
            app.panel_single_particles.Title = 'Determine single particles';
            app.label_r2 = uilabel(app.panel_single_particles);
            app.label_r2.Text = 'R^2';
            app.button_single_particles = uibutton(app.panel_single_particles, 'push');
            app.button_single_particles.ButtonPushedFcn = createCallbackFcn(app, @ButtonDetermineSingleParticls, true);
            app.button_single_particles.Text = {'Determine single'; ' particles'};
            app.entry_r2_min = uieditfield(app.panel_single_particles, 'numeric');
            app.entry_r2_min.Value = 0.9;
            app.label_r2_min = uilabel(app.panel_single_particles);
            app.label_r2_min.Text = 'Min';
            app.entry_lambda_max = uieditfield(app.panel_single_particles, 'numeric');
            app.entry_lambda_max.Value = 1.1;
            app.entry_lambda_min = uieditfield(app.panel_single_particles, 'numeric');
            app.entry_lambda_min.Value = 0.9;
            app.label_lambda_min = uilabel(app.panel_single_particles);
            app.label_lambda_min.Text = 'Min';
            app.label_lambda_max = uilabel(app.panel_single_particles);
            app.label_lambda_max.Text = 'Max';
            app.label_lambda = uilabel(app.panel_single_particles);
            app.label_lambda.Text = 'Lambda';
            app.entry_linewidth_max = uieditfield(app.panel_single_particles, 'numeric');
            app.entry_linewidth_max.Value = 1.1;
            app.entry_linewidth_min = uieditfield(app.panel_single_particles, 'numeric');
            app.entry_linewidth_min.Value = 0.9;
            app.label_linewidth_min = uilabel(app.panel_single_particles);
            app.label_linewidth_min.Text = 'Min';
            app.label_linewidth_max = uilabel(app.panel_single_particles);
            app.label_linewidth_max.Text = 'Max';
            app.label_linewidth = uilabel(app.panel_single_particles);
            app.label_linewidth.Text = 'Linewidth';
            app.panel_datasets = uipanel(app.UIFigure);
            app.panel_datasets.Title = 'Datasets';
            app.SpectraLabel = uilabel(app.panel_datasets);
            app.SpectraLabel.Text = 'Spectra';
            app.drop_spectra = uidropdown(app.panel_datasets);
            app.drop_spectra.Items = {'', 'Option 2', 'Option 3', 'Option 4'};
            app.drop_spectra.ValueChangedFcn = createCallbackFcn(app, @DropSpectraChanged, true);
            app.drop_spectra.Value = '';
            app.TimetraceDropDownLabel = uilabel(app.panel_datasets);
            app.TimetraceDropDownLabel.Text = 'Timetrace';
            app.drop_tt = uidropdown(app.panel_datasets);
            app.drop_tt.Items = {'', 'Option 2', 'Option 3', 'Option 4'};
            app.drop_tt.ValueChangedFcn = createCallbackFcn(app, @DropTTChanged, true);
            app.drop_tt.Value = '';
            app.lamp_spectra = uilamp(app.panel_datasets);
            app.lamp_tt = uilamp(app.panel_datasets);
            app.plot_contrast = uiaxes(app.UIFigure);
            title(app.plot_contrast, 'Contrast')
            xlabel(app.plot_contrast, 'Wavelength (nm)')
            ylabel(app.plot_contrast, 'Contrast (arb.u.)')
            app.panel_contrast_settings = uipanel(app.UIFigure);
            app.panel_contrast_settings.Title = 'Contrast settings';
            app.panel_show_particles = uibuttongroup(app.panel_contrast_settings);
            app.panel_show_particles.SelectionChangedFcn = createCallbackFcn(app, @ParticleSelectionChanged, true);
            app.panel_show_particles.Title = 'Show particles:';
            app.button_show_all = uitogglebutton(app.panel_show_particles);
            app.button_show_all.Text = 'All';
            app.button_show_all.Value = true;
            app.button_show_single = uitogglebutton(app.panel_show_particles);
            app.button_show_single.Text = 'Single';
            app.ContrastframeLabel = uilabel(app.panel_contrast_settings);
            app.ContrastframeLabel.Text = 'Contrast frame:';
            app.entry_contrast_frame = uieditfield(app.panel_contrast_settings, 'numeric');
            app.entry_contrast_frame.Limits = [0 Inf];
            app.entry_contrast_frame.ValueDisplayFormat = '%.0f';
            app.entry_contrast_frame.ValueChangedFcn = createCallbackFcn(app, @ContrastReferenceFrameChanged, true);
            app.entry_contrast_frame.Value = 6000;
            app.ContrastreferenceframeLabel = uilabel(app.panel_contrast_settings);
            app.ContrastreferenceframeLabel.Text = {'Contrast reference'; 'frame'};
            app.entry_ref_frame = uieditfield(app.panel_contrast_settings, 'numeric');
            app.entry_ref_frame.Limits = [4 Inf];
            app.entry_ref_frame.ValueDisplayFormat = '%.0f';
            app.entry_ref_frame.ValueChangedFcn = createCallbackFcn(app, @TTFrame, true);
            app.entry_ref_frame.Value = 200;
            app.panel_spectrum = uipanel(app.UIFigure);
            app.panel_spectrum.Title = 'Spectrum';
            app.plot_spectra = uiaxes(app.panel_spectrum);
            title(app.plot_spectra, 'Spectrum')
            xlabel(app.plot_spectra, 'Wavelength (nm)')
            ylabel(app.plot_spectra, 'Scat. cross sec. (arb.u.)')
            app.plot_spectra_label_SP_lambda = uilabel(app.panel_spectrum);
            app.plot_spectra_label_SP_gamma = uilabel(app.panel_spectrum);
            app.panel_manual_fit = uipanel(app.panel_spectrum);
            app.panel_manual_fit.Title = 'Manual spectrum fit';
            app.manual_fit_par2 = uieditfield(app.panel_manual_fit, 'numeric');
            app.ParametersEditFieldLabel = uilabel(app.panel_manual_fit);
            app.ParametersEditFieldLabel.Text = 'Parameters';
            app.manual_fit_par1 = uieditfield(app.panel_manual_fit, 'numeric');
            app.manual_fit_par4 = uieditfield(app.panel_manual_fit, 'numeric');
            app.manual_fit_button = uibutton(app.panel_manual_fit, 'push');
            app.manual_fit_button.ButtonPushedFcn = createCallbackFcn(app, @DoManualHSMFit, true);
            app.manual_fit_button.Text = 'Fit';
            app.manual_fit_button_save = uibutton(app.panel_manual_fit, 'push');
            app.manual_fit_button_save.ButtonPushedFcn = createCallbackFcn(app, @ManualFitSave, true);
            app.manual_fit_button_save.Text = 'Save';
            app.DatapointsLabel = uilabel(app.panel_manual_fit);
            app.DatapointsLabel.Text = 'Datapoints';
            app.manual_fit_datapoints = uieditfield(app.panel_manual_fit, 'text');
            app.manual_fit_par3 = uieditfield(app.panel_manual_fit, 'numeric');
            app.plot_hsm_fov = uiaxes(app.panel_spectrum);
            app.HSMFrameLabel = uilabel(app.panel_spectrum);
            app.HSMFrameLabel.Text = 'HSM Frame';
            app.slider_hsm_frame = uislider(app.panel_spectrum);
            app.slider_hsm_frame.MajorTickLabels = {'0', '20', '40', '60', '80', '100'};
            app.slider_hsm_frame.ValueChangingFcn = createCallbackFcn(app, @SliderHSMFrameChanging, true);
            app.slider_hsm_frame.MinorTicks = [];
            app.plot_spectra_label_SP_r2 = uilabel(app.panel_spectrum);
            app.panel_save = uipanel(app.UIFigure);
            app.panel_save.Title = 'Save figures';
            app.button_contrast = uibutton(app.panel_save, 'push');
            app.button_contrast.ButtonPushedFcn = createCallbackFcn(app, @ActionsContrast, true);
            app.button_contrast.Text = 'Contrast';
            app.button_save_particle = uibutton(app.panel_save, 'push');
            app.button_save_particle.ButtonPushedFcn = createCallbackFcn(app, @ActionsSaveParticle, true);
            app.button_save_particle.Text = 'Particle';
            app.button_save_all = uibutton(app.panel_save, 'push');
            app.button_save_all.ButtonPushedFcn = createCallbackFcn(app, @ActionSaveAll, true);
            app.button_save_all.Text = 'All Particles';
            app.panel_tt = uipanel(app.UIFigure);
            app.panel_tt.Title = 'Timetrace';
            app.plot_tt_fov = uiaxes(app.panel_tt);
            app.panel_tt_settings = uipanel(app.panel_tt);
            app.panel_tt_settings.Title = 'TT Settings';
            app.button_normalize = uibutton(app.panel_tt_settings, 'state');
            app.button_normalize.ValueChangedFcn = createCallbackFcn(app, @TTNormalize, true);
            app.button_normalize.Text = 'Normalize';
            app.button_manual_y_axis = uibutton(app.panel_tt_settings, 'state');
            app.button_manual_y_axis.ValueChangedFcn = createCallbackFcn(app, @TTManualAxis, true);
            app.button_manual_y_axis.Text = 'Manual Y axis';
            app.entry_y_axis_max = uieditfield(app.panel_tt_settings, 'numeric');
            app.entry_y_axis_max.ValueChangedFcn = createCallbackFcn(app, @TTYMaxChanged, true);
            app.entry_y_axis_max.Value = 1.1;
            app.entry_y_axis_min = uieditfield(app.panel_tt_settings, 'numeric');
            app.entry_y_axis_min.ValueChangedFcn = createCallbackFcn(app, @TTYMinChanged, true);
            app.entry_y_axis_min.Value = 0.9;
            app.button_rmv_spikes = uibutton(app.panel_tt_settings, 'state');
            app.button_rmv_spikes.ValueChangedFcn = createCallbackFcn(app, @TTRMVSpikes, true);
            app.button_rmv_spikes.Text = 'Remove spikes';
            app.label_y_axis_min = uilabel(app.panel_tt_settings);
            app.label_y_axis_min.Text = 'Min';
            app.label_y_axis_max = uilabel(app.panel_tt_settings);
            app.label_y_axis_max.Text = 'Max';
            app.plot_scatter = uiaxes(app.panel_tt);
            app.panel_tt_scatter_settings = uipanel(app.panel_tt);
            app.panel_tt_scatter_settings.Title = 'Scatter settings';
            app.checkbox_drift_correction = uicheckbox(app.panel_tt_scatter_settings);
            app.checkbox_drift_correction.ValueChangedFcn = createCallbackFcn(app, @DriftCorrectionCheckbox, true);
            app.checkbox_drift_correction.Text = {'Plot drift '; 'corrected '; 'results'};
            app.checkbox_show_drift_correction = uicheckbox(app.panel_tt_scatter_settings);
            app.checkbox_show_drift_correction.ValueChangedFcn = createCallbackFcn(app, @DriftCorrectionShowCheckbox, true);
            app.checkbox_show_drift_correction.Text = {'Show drift'; 'correction'};
            app.plot_tt = uiaxes(app.panel_tt);
            title(app.plot_tt, 'Title')
            xlabel(app.plot_tt, 'Time (s)')
            ylabel(app.plot_tt, 'Scattered intensity (arb.u.)')
            app.slider_frame = uislider(app.panel_tt);
            app.slider_frame.ValueChangingFcn = createCallbackFcn(app, @SliderFrameChanging, true);
            app.label_time = uilabel(app.panel_tt);
            app.label_time.Text = 'Time (s):';
            app.field_time = uieditfield(app.panel_tt, 'numeric');
            app.field_time.Editable = 'off';
            app.label_frame_spinner = uilabel(app.panel_tt);
            app.label_frame_spinner.Text = 'Frame:';
            app.spinner_frame = uispinner(app.panel_tt);
            app.spinner_frame.ValueChangedFcn = createCallbackFcn(app, @SpinnerFrameChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Page_Visualization(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end